classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResultLabel                     matlab.ui.control.Label
        PredictRiskButton               matlab.ui.control.Button
        AgeField                        matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        OldpeakField                    matlab.ui.control.NumericEditField
        STDepressionoldpeakEditFieldLabel  matlab.ui.control.Label
        HeartRateField                  matlab.ui.control.NumericEditField
        MaxHeartRatethalachEditFieldLabel  matlab.ui.control.Label
        CholField                       matlab.ui.control.NumericEditField
        CholesterolcholEditFieldLabel   matlab.ui.control.Label
        BPField                         matlab.ui.control.NumericEditField
        RestingBPtrestbpsEditFieldLabel  matlab.ui.control.Label
        ThalDropDown                    matlab.ui.control.DropDown
        ThalassemiathalDropDownLabel    matlab.ui.control.Label
        VesselsDropDown                 matlab.ui.control.DropDown
        ofMajorVesselscaDropDownLabel   matlab.ui.control.Label
        SlopeDropDown                   matlab.ui.control.DropDown
        SlopeofSTslopeDropDownLabel     matlab.ui.control.Label
        ExangDropDown                   matlab.ui.control.DropDown
        ExerciseInducedAnginaexangDropDownLabel  matlab.ui.control.Label
        ECGDropDown                     matlab.ui.control.DropDown
        RestingECGrestecgDropDownLabel  matlab.ui.control.Label
        FBSDropDown                     matlab.ui.control.DropDown
        FastingBloodSugarfbsDropDownLabel  matlab.ui.control.Label
        CPDropDown                      matlab.ui.control.DropDown
        ChestPainTypecpDropDownLabel    matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictRiskButton
        function PredictRiskButtonPushed(app, event)
            % === Map dropdown selections to model input values ===
    sex = strcmp(app.SexDropDown.Value, 'Male');

    cp_map = containers.Map({'Typical Angina', 'Atypical Angina', 'Non-anginal Pain', 'Asymptomatic'}, [0 1 2 3]);
    cp = cp_map(app.CPDropDown.Value);

    fbs = strcmp(app.FBSDropDown.Value, '>120 mg/dL (yes)');

    ecg_map = containers.Map({'Normal', 'ST-T Abnormality', 'Left Ventricular Hypertrophy'}, [0 1 2]);
    restecg = ecg_map(app.ECGDropDown.Value);

    exang = strcmp(app.ExangDropDown.Value, 'Yes');

    slope_map = containers.Map({'Upsloping', 'Flat', 'Downsloping'}, [0 1 2]);
    slope = slope_map(app.SlopeDropDown.Value);

    ca = str2double(app.VesselsDropDown.Value);

    thal_map = containers.Map({'Normal', 'Fixed Defect', 'Reversible Defect'}, [1 2 3]);
    thal = thal_map(app.ThalDropDown.Value);

    % === Get numeric input values ===
    age = app.AgeField.Value;
    trestbps = app.BPField.Value;
    chol = app.CholField.Value;
    thalach = app.HeartRateField.Value;
    oldpeak = app.OldpeakField.Value;

    % === Construct feature vector for prediction ===
    inputRow = [age, sex, cp, trestbps, chol, fbs, restecg, thalach, ...
                exang, oldpeak, slope, ca, thal];

    % === Load trained model ===
    modelFile = 'heart_disease_model.mat';
    if exist(modelFile, 'file')
        load(modelFile, 'treeModel');
    else
        uialert(app.UIFigure, 'Model file not found.', 'Error');
        return;
    end

    % === Make prediction ===
    prediction = predict(treeModel, inputRow);

    % === Display result ===
    if prediction == 1
        app.ResultLabel.Text = 'Prediction: At Risk of Heart Disease';
        app.ResultLabel.FontColor = [1 0 0]; % red
    else
        app.ResultLabel.Text = 'Prediction: Not at Risk';
        app.ResultLabel.FontColor = [0 0.5 0]; % green
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Heart Disease Risk Predictor';
            app.SexDropDownLabel = uilabel(app.UIFigure);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.UIFigure);
            app.SexDropDown.Items = {'Male', 'Female'};
            app.SexDropDown.Value = 'Male';
            app.ChestPainTypecpDropDownLabel = uilabel(app.UIFigure);
            app.ChestPainTypecpDropDownLabel.Text = 'Chest Pain Type (cp)';
            app.CPDropDown = uidropdown(app.UIFigure);
            app.CPDropDown.Items = {'Typical Angina', 'Atypical Angina', 'Non-anginal Pain', 'Asymptomatic'};
            app.CPDropDown.Value = 'Typical Angina';
            app.FastingBloodSugarfbsDropDownLabel = uilabel(app.UIFigure);
            app.FastingBloodSugarfbsDropDownLabel.Text = 'Fasting Blood Sugar (fbs)';
            app.FBSDropDown = uidropdown(app.UIFigure);
            app.FBSDropDown.Items = {'>120 mg/dL (yes)', '≤ 120 mg/dL (No)'};
            app.FBSDropDown.Value = '>120 mg/dL (yes)';
            app.RestingECGrestecgDropDownLabel = uilabel(app.UIFigure);
            app.RestingECGrestecgDropDownLabel.Text = 'Resting ECG (restecg)';
            app.ECGDropDown = uidropdown(app.UIFigure);
            app.ECGDropDown.Items = {'Normal', 'ST-T Abnormality', 'Left Ventricular Hypertrophy'};
            app.ECGDropDown.Value = 'Normal';
            app.ExerciseInducedAnginaexangDropDownLabel = uilabel(app.UIFigure);
            app.ExerciseInducedAnginaexangDropDownLabel.Text = 'Exercise-Induced Angina (exang)	';
            app.ExangDropDown = uidropdown(app.UIFigure);
            app.ExangDropDown.Items = {'Yes', 'No'};
            app.ExangDropDown.Value = 'Yes';
            app.SlopeofSTslopeDropDownLabel = uilabel(app.UIFigure);
            app.SlopeofSTslopeDropDownLabel.Text = '	Slope of ST (slope)';
            app.SlopeDropDown = uidropdown(app.UIFigure);
            app.SlopeDropDown.Items = {'Upsloping', 'Flat', 'Downsloping'};
            app.SlopeDropDown.Value = 'Upsloping';
            app.ofMajorVesselscaDropDownLabel = uilabel(app.UIFigure);
            app.ofMajorVesselscaDropDownLabel.Text = '# of Major Vessels (ca)	';
            app.VesselsDropDown = uidropdown(app.UIFigure);
            app.VesselsDropDown.Items = {'0', '1', '2', '3'};
            app.VesselsDropDown.Value = '0';
            app.ThalassemiathalDropDownLabel = uilabel(app.UIFigure);
            app.ThalassemiathalDropDownLabel.Text = 'Thalassemia (thal)	';
            app.ThalDropDown = uidropdown(app.UIFigure);
            app.ThalDropDown.Items = {'Normal', 'Fixed Defect', 'Reversible Defect'};
            app.ThalDropDown.Value = 'Normal';
            app.RestingBPtrestbpsEditFieldLabel = uilabel(app.UIFigure);
            app.RestingBPtrestbpsEditFieldLabel.Text = 'Resting BP (trestbps)';
            app.BPField = uieditfield(app.UIFigure, 'numeric');
            app.CholesterolcholEditFieldLabel = uilabel(app.UIFigure);
            app.CholesterolcholEditFieldLabel.Text = 'Cholesterol (chol)	';
            app.CholField = uieditfield(app.UIFigure, 'numeric');
            app.MaxHeartRatethalachEditFieldLabel = uilabel(app.UIFigure);
            app.MaxHeartRatethalachEditFieldLabel.Text = 'Max Heart Rate (thalach)	';
            app.HeartRateField = uieditfield(app.UIFigure, 'numeric');
            app.STDepressionoldpeakEditFieldLabel = uilabel(app.UIFigure);
            app.STDepressionoldpeakEditFieldLabel.Text = 'ST Depression (oldpeak)	';
            app.OldpeakField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeField = uieditfield(app.UIFigure, 'numeric');
            app.PredictRiskButton = uibutton(app.UIFigure, 'push');
            app.PredictRiskButton.ButtonPushedFcn = createCallbackFcn(app, @PredictRiskButtonPushed, true);
            app.PredictRiskButton.Text = 'Predict Risk';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Chance of  Heart Disease';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end